classdef EqualizerApp_VoronkovaM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        gain10       matlab.ui.control.Slider
        Label_6      matlab.ui.control.Label
        gain9        matlab.ui.control.Slider
        Label_5      matlab.ui.control.Label
        gain8        matlab.ui.control.Slider
        Label_4      matlab.ui.control.Label
        gain7        matlab.ui.control.Slider
        Label_3      matlab.ui.control.Label
        gain6        matlab.ui.control.Slider
        Label_2      matlab.ui.control.Label
        gain5        matlab.ui.control.Slider
        HzLabel_4    matlab.ui.control.Label
        gain4        matlab.ui.control.Slider
        HzLabel_3    matlab.ui.control.Label
        gain3        matlab.ui.control.Slider
        HzLabel_2    matlab.ui.control.Label
        gain2        matlab.ui.control.Slider
        HzLabel      matlab.ui.control.Label
        gain1        matlab.ui.control.Slider
        Label        matlab.ui.control.Label
        StopButton   matlab.ui.control.Button
        PauseButton  matlab.ui.control.Button
        PlayButton   matlab.ui.control.Button
        LoadButton   matlab.ui.control.Button
        UIAxes_1     matlab.ui.control.UIAxes
        UIAxes_2     matlab.ui.control.UIAxes
    end

    properties (Access = public)
        equalizer = Equalizer(64, 44100);
        deviceWriter = audioDeviceWriter('SampleRate', 44100);
        fileReader = dsp.AudioFileReader();
    end
    properties (Access = protected)
        filename 
        stopFlag = false;
    end

    methods (Access = private)
        function PlotFreqResponse(app)
            [H, w] = GetFreqResponse(app.equalizer);
            plot (app.UIAxes_2, w, H);
            drawnow
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PlotFreqResponse();
            drawnow
        end

        % Value changed function: gain1
        function gain1ValueChanged(app, event)
            app.equalizer.gain(1) = app.gain1.Value;
            app.PlotFreqResponse();
            drawnow
        end

        % Value changed function: gain2
        function gain2ValueChanged(app, event)
            app.equalizer.gain(2) = app.gain2.Value;
            app.PlotFreqResponse();
            drawnow
        end

        % Value changed function: gain3
        function gain3ValueChanged(app, event)
            app.equalizer.gain(3) = app.gain3.Value;
            app.PlotFreqResponse();
            drawnow 
        end

        % Value changed function: gain4
        function gain4ValueChanged(app, event)
            app.equalizer.gain(4) = app.gain4.Value;
            app.PlotFreqResponse();
            drawnow 
        end

        % Value changed function: gain5
        function gain5ValueChanged(app, event)
            app.equalizer.gain(5) = app.gain5.Value;
            app.PlotFreqResponse();
            drawnow 
        end

        % Value changed function: gain6
        function gain6ValueChanged(app, event)
            app.equalizer.gain(6) = app.gain6.Value;
            app.PlotFreqResponse();
            drawnow 
        end

        % Value changed function: gain7
        function gain7ValueChanged(app, event)
            app.equalizer.gain(7) = app.gain7.Value;
            app.PlotFreqResponse();
            drawnow 
        end

        % Value changed function: gain8
        function gain8ValueChanged(app, event)
            app.equalizer.gain(8) = app.gain8.Value;
            app.PlotFreqResponse();
            drawnow 
        end

        % Value changed function: gain9
        function gain9ValueChanged(app, event)
            app.equalizer.gain(9) = app.gain9.Value;
            app.PlotFreqResponse();
            drawnow
        end

        % Value changed function: gain10
        function gain10ValueChanged(app, event)
            app.equalizer.gain(10) = app.gain10.Value;
            app.PlotFreqResponse();
            drawnow 
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.filename = uigetfile('*.mp3');
            app.fileReader = dsp.AudioFileReader(app.filename, 'PlayCount', inf, 'SamplesPerFrame', 4096);
            drawnow
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.stopFlag = false;
            while ~isDone(app.fileReader) && ~app.stopFlag
                audioData = app.fileReader();
                dataPortionFiltering = app.equalizer.Filtering(audioData);
                app.deviceWriter(dataPortionFiltering);
                [pxx, f] = pspectrum(audioData, app.equalizer.fs);
                plot(app.UIAxes_1, f, pxx)
                drawnow
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.stopFlag = true;
            drawnow
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stopFlag = false;
            reset(app.deviceWriter);
            reset(app.fileReader);
            drawnow
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'FreqResponce')
            xlabel(app.UIAxes_2, 'f')
            ylabel(app.UIAxes_2, 'dB')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.YTick = [0 0.5 1];
            app.UIAxes_1 = uiaxes(app.UIFigure);
            title(app.UIAxes_1, 'Spectrum')
            xlabel(app.UIAxes_1, 'f')
            ylabel(app.UIAxes_1, 'dB')
            zlabel(app.UIAxes_1, 'Z')
            app.UIAxes_1.XTick = [0 0.25 0.5 0.75 1];
            app.UIAxes_1.XTickLabel = {'0'; '0.25'; '0.5'; '0.75'; '1'};
            app.UIAxes_1.YTick = [0 0.2 0.4 0.6 0.8 1];
            colormap(app.UIAxes_1, 'copper')
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '31';
            app.gain1 = uislider(app.UIFigure);
            app.gain1.Limits = [-10 10];
            app.gain1.MajorTicks = [-10 0 10];
            app.gain1.ValueChangedFcn = createCallbackFcn(app, @gain1ValueChanged, true);
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = '62 ';
            app.gain2 = uislider(app.UIFigure);
            app.gain2.Limits = [-10 10];
            app.gain2.MajorTicks = [-10 0 10];
            app.gain2.ValueChangedFcn = createCallbackFcn(app, @gain2ValueChanged, true);
            app.HzLabel_2 = uilabel(app.UIFigure);
            app.HzLabel_2.Text = '125 ';
            app.gain3 = uislider(app.UIFigure);
            app.gain3.Limits = [-10 10];
            app.gain3.MajorTicks = [-10 0 10];
            app.gain3.ValueChangedFcn = createCallbackFcn(app, @gain3ValueChanged, true);
            app.HzLabel_3 = uilabel(app.UIFigure);
            app.HzLabel_3.Text = '250 ';
            app.gain4 = uislider(app.UIFigure);
            app.gain4.Limits = [-10 10];
            app.gain4.MajorTicks = [-10 0 10];
            app.gain4.ValueChangedFcn = createCallbackFcn(app, @gain4ValueChanged, true);
            app.HzLabel_4 = uilabel(app.UIFigure);
            app.HzLabel_4.Text = '500 ';
            app.gain5 = uislider(app.UIFigure);
            app.gain5.Limits = [-10 10];
            app.gain5.MajorTicks = [-10 0 10];
            app.gain5.ValueChangedFcn = createCallbackFcn(app, @gain5ValueChanged, true);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '1000';
            app.gain6 = uislider(app.UIFigure);
            app.gain6.Limits = [-10 10];
            app.gain6.MajorTicks = [-10 0 10];
            app.gain6.ValueChangedFcn = createCallbackFcn(app, @gain6ValueChanged, true);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '2000';
            app.gain7 = uislider(app.UIFigure);
            app.gain7.Limits = [-10 10];
            app.gain7.MajorTicks = [-10 0 10];
            app.gain7.ValueChangedFcn = createCallbackFcn(app, @gain7ValueChanged, true);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '4000';
            app.gain8 = uislider(app.UIFigure);
            app.gain8.Limits = [-10 10];
            app.gain8.MajorTicks = [-10 0 10];
            app.gain8.ValueChangedFcn = createCallbackFcn(app, @gain8ValueChanged, true);
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '8000';
            app.gain9 = uislider(app.UIFigure);
            app.gain9.Limits = [-10 10];
            app.gain9.MajorTicks = [-10 0 10];
            app.gain9.ValueChangedFcn = createCallbackFcn(app, @gain9ValueChanged, true);
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '16000';
            app.gain10 = uislider(app.UIFigure);
            app.gain10.Limits = [-10 10];
            app.gain10.MajorTicks = [-10 0 10];
            app.gain10.ValueChangedFcn = createCallbackFcn(app, @gain10ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EqualizerApp_VoronkovaM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end